classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartFileEditFieldLabel         matlab.ui.control.Label
        StartFileEditField              matlab.ui.control.EditField
        XAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        YAxisDropDown                   matlab.ui.control.DropDown
        UpdateDataSetButton             matlab.ui.control.Button
        StartGateVoltageEditFieldLabel  matlab.ui.control.Label
        StartGateVoltageEditField       matlab.ui.control.EditField
        FinalGateVoltageEditFieldLabel  matlab.ui.control.Label
        FinalGateVoltageEditField       matlab.ui.control.EditField
        GateVoltageStepEditFieldLabel   matlab.ui.control.Label
        GateVoltageStepEditField        matlab.ui.control.EditField
        ZAxisDropDownLabel              matlab.ui.control.Label
        ZAxisDropDown                   matlab.ui.control.DropDown
        UIAxes                          matlab.ui.control.UIAxes
        ColormapDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            data = load("mappingdata.mat");
            disableDefaultInteractivity(app.UIAxes)

            x = app.XAxisDropDown.Value;
            y = app.YAxisDropDown.Value;
            z = app.ZAxisDropDown.Value;
            
            minx = min(min(data.dat.(x))); maxx = max(max(data.dat.(x)));
            miny = min(min(data.dat.(y))); maxy = max(max(data.dat.(y)));
            minz = min(min(data.dat.(z))); maxz = max(max(data.dat.(z)));  
            map = pcolor(app.UIAxes,data.dat.(x),data.dat.(y),data.dat.(z));
  
            xlim(app.UIAxes, [minx maxx])
            ylim(app.UIAxes, [miny maxy])
           
            set(map,'EdgeColor','none');
            shading(app.UIAxes,'interp');
            colorbar(app.UIAxes)
            

        end

        % Value changed function: StartFileEditField
        function StartFileEditFieldValueChanged(app, event)
            value2 = app.StartFileEditField.Value;
            
        end

        % Button pushed function: UpdateDataSetButton
        function UpdateDataSetButtonPushed(app, event)
            start_file = str2num(app.StartFileEditField.Value);
            start_gate = str2num(app.StartGateVoltageEditField.Value);
            final_gate = str2num(app.FinalGateVoltageEditField.Value);
            delvg = str2num(app.GateVoltageStepEditField.Value);
            
            data = bgmapping(start_file,start_gate,delvg,final_gate,'s')
            app.XAxisDropDown.Items = fieldnames(data);
            app.YAxisDropDown.Items = fieldnames(data);
            app.ZAxisDropDown.Items = fieldnames(data);
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
           data = load("mappingdata.mat");
            disableDefaultInteractivity(app.UIAxes)

            x = app.XAxisDropDown.Value;
            y = app.YAxisDropDown.Value;
            z = app.ZAxisDropDown.Value;
            
            minx = min(min(data.dat.(x))); maxx = max(max(data.dat.(x)));
            miny = min(min(data.dat.(y))); maxy = max(max(data.dat.(y)));
            minz = min(min(data.dat.(z))); maxz = max(max(data.dat.(z)));  
            map = pcolor(app.UIAxes,data.dat.(x),data.dat.(y),data.dat.(z));
  
            xlim(app.UIAxes, [minx maxx])
            ylim(app.UIAxes, [miny maxy])
           
            set(map,'EdgeColor','none');
            shading(app.UIAxes,'interp');
            colorbar(app.UIAxes)
            
        end

        % Value changing function: StartFileEditField
        function StartFileEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ZAxisDropDown
        function ZAxisDropDownValueChanged(app, event)
           data = load("mappingdata.mat");
            disableDefaultInteractivity(app.UIAxes)

            x = app.XAxisDropDown.Value;
            y = app.YAxisDropDown.Value;
            z = app.ZAxisDropDown.Value;
            
            minx = min(min(data.dat.(x))); maxx = max(max(data.dat.(x)));
            miny = min(min(data.dat.(y))); maxy = max(max(data.dat.(y)));
            minz = min(min(data.dat.(z))); maxz = max(max(data.dat.(z)));  
            map = pcolor(app.UIAxes,data.dat.(x),data.dat.(y),data.dat.(z));
  
            xlim(app.UIAxes, [minx maxx])
            ylim(app.UIAxes, [miny maxy])
           
            set(map,'EdgeColor','none');
            shading(app.UIAxes,'interp');
            colorbar(app.UIAxes)
            
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
                value = app.ColormapDropDown.Value;
                colorscheme = othercolor(value,1024);
                flippedJetColorMap = (colorscheme);
                colormap(app.UIFigure,flippedJetColorMap);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartFileEditFieldLabel = uilabel(app.UIFigure);
            app.StartFileEditFieldLabel.Text = 'Start File';
            app.StartFileEditField = uieditfield(app.UIFigure, 'text');
            app.StartFileEditField.ValueChangedFcn = createCallbackFcn(app, @StartFileEditFieldValueChanged, true);
            app.StartFileEditField.ValueChangingFcn = createCallbackFcn(app, @StartFileEditFieldValueChanging, true);
            app.XAxisDropDownLabel = uilabel(app.UIFigure);
            app.XAxisDropDownLabel.Text = 'X Axis';
            app.XAxisDropDown = uidropdown(app.UIFigure);
            app.XAxisDropDown.Items = {'Option 1', 'Option 5', 'Option 2', 'Option 3', 'Option 4'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.YAxisDropDownLabel = uilabel(app.UIFigure);
            app.YAxisDropDownLabel.Text = 'Y Axis';
            app.YAxisDropDown = uidropdown(app.UIFigure);
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.UpdateDataSetButton = uibutton(app.UIFigure, 'push');
            app.UpdateDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataSetButtonPushed, true);
            app.UpdateDataSetButton.Text = 'Update Data Set';
            app.StartGateVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.StartGateVoltageEditFieldLabel.Text = 'Start Gate Voltage';
            app.StartGateVoltageEditField = uieditfield(app.UIFigure, 'text');
            app.FinalGateVoltageEditFieldLabel = uilabel(app.UIFigure);
            app.FinalGateVoltageEditFieldLabel.Text = 'Final Gate Voltage';
            app.FinalGateVoltageEditField = uieditfield(app.UIFigure, 'text');
            app.GateVoltageStepEditFieldLabel = uilabel(app.UIFigure);
            app.GateVoltageStepEditFieldLabel.Text = 'Gate Voltage Step';
            app.GateVoltageStepEditField = uieditfield(app.UIFigure, 'text');
            app.ZAxisDropDownLabel = uilabel(app.UIFigure);
            app.ZAxisDropDownLabel.Text = 'Z Axis';
            app.ZAxisDropDown = uidropdown(app.UIFigure);
            app.ZAxisDropDown.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', ''};
            app.ZAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxisDropDownValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'Accent3', 'Accent4', 'Accent5', 'Accent6', 'Accent7', 'Accent8', 'Blues3', 'Blues4', 'Blues5', 'Blues6', 'Blues7', 'Blues8', 'Blues9', 'BrBG10', 'BrBG11', 'BrBG3', 'BrBG4', 'BrBG5', 'BrBG6', 'BrBG7', 'BrBG8', 'BrBG9', 'BuGn3', 'BuGn4', 'BuGn5', 'BuGn6', 'BuGn7', 'BuGn8', 'BuGn9', 'BuPu3', 'BuPu4', 'BuPu5', 'BuPu6', 'BuPu7', 'BuPu8', 'BuPu9', 'Dark23', 'Dark24', 'Dark25', 'Dark26', 'Dark27', 'Dark28', 'GnBu3', 'GnBu4', 'GnBu5', 'GnBu6', 'GnBu7', 'GnBu8', 'GnBu9', 'Greens3', 'Greens4', 'Greens5', 'Greens6', 'Greens7', 'Greens8', 'Greens9', 'Greys3', 'Greys4', 'Greys5', 'Greys6', 'Greys7', 'Greys8', 'Greys9', 'OrRd3', 'OrRd4', 'OrRd5', 'OrRd6', 'OrRd7', 'OrRd8', 'OrRd9', 'Oranges3', 'Oranges4', 'Oranges5', 'Oranges6', 'Oranges7', 'Oranges8', 'Oranges9', 'PRGn10', 'PRGn11', 'PRGn3', 'PRGn4', 'PRGn5', 'PRGn6', 'PRGn7', 'PRGn8', 'PRGn9', 'Paired10', 'Paired11', 'Paired12', 'Paired3', 'Paired4', 'Paired5', 'Paired6', 'Paired7', 'Paired8', 'Paired9', 'Pastel13', 'Pastel14', 'Pastel15', 'Pastel16', 'Pastel17', 'Pastel18', 'Pastel19', 'Pastel23', 'Pastel24', 'Pastel25', 'Pastel26', 'Pastel27', 'Pastel28', 'PiYG10', 'PiYG11', 'PiYG3', 'PiYG4', 'PiYG5', 'PiYG6', 'PiYG7', 'PiYG8', 'PiYG9', 'PuBu3', 'PuBu4', 'PuBu5', 'PuBu6', 'PuBu7', 'PuBu8', 'PuBu9', 'PuBuGn3', 'PuBuGn4', 'PuBuGn5', 'PuBuGn6', 'PuBuGn7', 'PuBuGn8', 'PuBuGn9', 'PuOr10', 'PuOr11', 'PuOr3', 'RdYlBu10', 'RdYlBu11', 'RdYlBu3', 'RdYlBu4', 'RdYlBu5', 'RdYlBu6', 'RdYlBu7', 'RdYlBu8', 'RdYlBu9', 'RdYlGn10', 'RdYlGn11', 'RdYlGn3', 'RdYlGn4', 'RdYlGn5', 'RdYlGn6', 'RdYlGn7', 'RdYlGn8', 'RdYlGn9', 'Reds3', 'Reds4', 'Reds5', 'Reds6', 'Reds7', 'Reds8', 'Reds9', 'Set13', 'Set14', 'Set15', 'Set16', 'Set17', 'Set18', 'Set19', 'Set23', 'Set24', 'Set25', 'Set26', 'Set27', 'Set28', 'Set310', 'Set311', 'Set312', 'Set33', 'Set34', 'Set35', 'Set36', 'Set37', 'Set38', 'Set39', 'Spectral10', 'Spectral11', 'Spectral3', 'Spectral4', 'Spectral5', 'Spectral6', 'Spectral7', 'Spectral8', 'Spectral9', 'YlGn3', 'YlGn4', 'YlGn5', 'YlGn6', 'YlGn7', 'YlGn8', 'YlGn9', 'YlGnBu3', 'YlGnBu4', 'YlGnBu5', 'YlGnBu6', 'YlGnBu7', 'YlGnBu8', 'YlGnBu9', 'YlOrBr3', 'YlOrBr4', 'YlOrBr5', 'YlOrBr6', 'YlOrBr7', 'YlOrBr8', 'YlOrBr9', 'YlOrRd3', 'YlOrRd4', 'YlOrRd5', 'YlOrRd6', 'YlOrRd7', 'YlOrRd8', 'YlOrRd9', ''};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Accent3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end